classdef app_2048 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Panel     matlab.ui.container.Panel
        Label     matlab.ui.control.Label
    end

    
    properties (Access = private)
        Grid double                         % 4x4 matrix storing tile values
        GameOver logical                    % true if game has ended
        TileLabels matlab.ui.control.Label  % 4x4 pole labelů (uloženo jako 16×1, indexujeme řádek/sloupec)
    end
    
    methods (Access = private)
        function createTileLabels(app)
            % Vytvoří 4×4 mřížku labelů uvnitř app.Panel na základě Label
            % šablony

            % Rozměry panelu (v pixelech)
            panelPos = app.Panel.Position;  % [x y width height]
            panelW = panelPos(3);
            panelH = panelPos(4);

            padding = 10;  % mezera mezi dlaždicemi a okrajem panelu
            n = 4;         % 4 sloupce/řádky
            tileSize = floor((panelW - (n+1)*padding) / n);

            % Inicializace matice pro labely
            app.TileLabels = matlab.ui.control.Label.empty;

            for row = 1:4
                for col = 1:4
                    % Vypočítat pozici (x,y) uvnitř panelu
                    x = padding + (col-1)*(tileSize + padding);
                    y = panelH - padding - row*(tileSize + padding) + padding;

                    % Vytvořit nový label ze šablony
                    lbl = uilabel(app.Panel);
                    lbl.FontSize = app.Label.FontSize;
                    lbl.FontWeight = app.Label.FontWeight;
                    lbl.HorizontalAlignment = app.Label.HorizontalAlignment;
                    lbl.VerticalAlignment = app.Label.VerticalAlignment;
                    lbl.FontColor = app.Label.FontColor;
                    lbl.Position = [x y tileSize tileSize];
                    lbl.BackgroundColor = '#bdac97';
                    lbl.Text = '';
                    
                    % Since TileLabels is 1D vector, calculate the index
                    idx = (row-1)*4 + col;
                    app.TileLabels(idx) = lbl;
                end
            end
        end
        
        function setStartingTiles(app)
            for i=1:2
                app.spawnNewTile();
            end
        end
        
        function spawnNewTile(app)
            % Najdi volná místa v gridu
            emptyIndices = find(app.Grid == 0);
        
            if isempty(emptyIndices)
                warning('ran out of grid space, game over');
                return;
            end
        
            % Vyber jednu náhodnou volnou pozici
            idx = emptyIndices(randi(numel(emptyIndices)));
            row = ceil(idx / 4);
            col = mod(idx - 1, 4) + 1;
        
            % Rozhodni hodnotu (2 nebo 4)
            if rand() < 0.1
                value = 4;
            else
                value = 2;
            end
        
            % Nastav do gridu
            app.Grid(row, col) = value;
        
            % Aktualizuj label
            linearIdx = (row - 1) * 4 + col;
            
            app.updateTileValue(linearIdx, value);

            disp(app.Grid);
        end
        
        function updateTileValue(app, pos, val)
            app.TileLabels(pos).Text = string(val);

            switch val
                case 2
                    bgColor = '#eee4da';
                case 4
                    bgColor = '#ebd8b6';
                case 8
                    bgColor = '#f2ae73';
                case 16
                    bgColor = [245, 149, 99] / 255;
                case 32
                    bgColor = [246, 124, 95] / 255;
                case 64
                    bgColor = [246, 94, 59] / 255;
                case 128
                    bgColor = [237, 207, 114] / 255;
                case 256
                    bgColor = [237, 204, 97] / 255;
                case 512
                    bgColor = [237, 200, 80] / 255;
                case 1024
                    bgColor = [237, 197, 63] / 255;
                case 2048
                    bgColor = [237, 194, 46] / 255;
                otherwise
                    % For values greater than 2048
                    bgColor = [60, 58, 50] / 255;
            end
        
            % Set the background color
            app.TileLabels(pos).BackgroundColor = bgColor;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Inicializace herního stavu a UI
            app.Grid = zeros(4,4);
            app.GameOver = false;

            % Dynamicky vytvořit 16 labelů podle šablony uvnitř panelu
            app.createTileLabels();

            % Smazat šablonový label (není třeba zobrazovat)
            app.Label.Visible = 'off';
            app.Label = matlab.ui.control.Label.empty;

            % Generate starting tiles
            app.setStartingTiles();

            % Spawn two initial tiles
            %app.spawnNewTile();
            %app.spawnNewTile();
            %app.updateUI();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Label = uilabel(app.Panel);
            app.Label.Text = '2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_2048

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end